classdef dataForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        table                   matlab.ui.control.Table
        eliminar                matlab.ui.control.Button
        anular                  matlab.ui.control.Button
        modificar               matlab.ui.control.Button
        yValue                  matlab.ui.control.NumericEditField
        ValorYEditFieldLabel    matlab.ui.control.Label
        guardar                 matlab.ui.control.Button
        xValue                  matlab.ui.control.NumericEditField
        ValorXEditFieldLabel    matlab.ui.control.Label
        FormulariodeDatosLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        dataId % Variable global para el id para cuando se necesite modificar algo en la tabal segun el id
    end
    
    methods (Access = private)
        
        function funcionLimpiar(app)
            app.table.Data = consultaDato;
            app.xValue.Value = 0;
            app.yValue.Value = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.table.Data = consultaDato;
        end

        % Button pushed function: modificar
        function modificarButtonPushed(app, event)
            valueX = app.xValue.Value;
            valueY = app.yValue.Value;
            modificarDato(app.dataId, valueX, valueY)
            funcionLimpiar(app)
            msgbox("Registro Modificado!")
        end

        % Button pushed function: guardar
        function guardarButtonPushed(app, event)
            valueX = app.xValue.Value;
            valueY = app.yValue.Value;
            insertarDato(valueX, valueY, 1)
            funcionLimpiar(app)
            msgbox("Registro Ingresado")
        end

        % Selection changed function: table
        function tableSelectionChanged(app, event)
            selection = app.table.Selection;
            if ~isempty(selection)
                selectRow = selection(1);
                selecId = app.table.Data{selectRow, 1};
                xValueSelect = app.table.Data{selectRow, 2};
                yValueSelect = app.table.Data{selectRow, 3};
                app.xValue.Value = xValueSelect;
                app.yValue.Value = yValueSelect;
                app.dataId = selecId;
            end
        end

        % Button pushed function: anular
        function anularButtonPushed(app, event)
            anularDato(app.dataId)
            funcionLimpiar(app)
            msgbox("Registro Anulado!")
        end

        % Button pushed function: eliminar
        function eliminarButtonPushed(app, event)
            eliminarDato(app.dataId)
            funcionLimpiar(app)
            msgbox("Registro Eliminado!")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FormulariodeDatosLabel = uilabel(app.UIFigure);
            app.FormulariodeDatosLabel.Text = 'Formulario de Datos';
            app.ValorXEditFieldLabel = uilabel(app.UIFigure);
            app.ValorXEditFieldLabel.Text = 'Valor X';
            app.xValue = uieditfield(app.UIFigure, 'numeric');
            app.guardar = uibutton(app.UIFigure, 'push');
            app.guardar.ButtonPushedFcn = createCallbackFcn(app, @guardarButtonPushed, true);
            app.guardar.Text = 'Guardar';
            app.ValorYEditFieldLabel = uilabel(app.UIFigure);
            app.ValorYEditFieldLabel.Text = 'Valor Y';
            app.yValue = uieditfield(app.UIFigure, 'numeric');
            app.modificar = uibutton(app.UIFigure, 'push');
            app.modificar.ButtonPushedFcn = createCallbackFcn(app, @modificarButtonPushed, true);
            app.modificar.Text = 'Modificar';
            app.anular = uibutton(app.UIFigure, 'push');
            app.anular.ButtonPushedFcn = createCallbackFcn(app, @anularButtonPushed, true);
            app.anular.Text = 'Anular';
            app.eliminar = uibutton(app.UIFigure, 'push');
            app.eliminar.ButtonPushedFcn = createCallbackFcn(app, @eliminarButtonPushed, true);
            app.eliminar.Text = 'Eliminar';
            app.table = uitable(app.UIFigure);
            app.table.ColumnName = {'ID DATO'; 'VALOR X'; 'VALOR Y'};
            app.table.RowName = {};
            app.table.SelectionChangedFcn = createCallbackFcn(app, @tableSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end